classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ConvolutionTab               matlab.ui.container.Tab
        KernelSizeLabel              matlab.ui.control.Label
        KernelSizeSlider             matlab.ui.control.Slider
        KernelSizeSliderLabel        matlab.ui.control.Label
        RandomKernelButton           matlab.ui.control.Button
        AddImageConvolutionButton    matlab.ui.control.Button
        ImgOriAxes                   matlab.ui.control.UIAxes
        ImgConvAxes                  matlab.ui.control.UIAxes
        LowPassFilterTab             matlab.ui.container.Tab
        FrequencyDomainPanel         matlab.ui.container.Panel
        CutoffLPFSlider              matlab.ui.control.Slider
        CutoffSliderLabel            matlab.ui.control.Label
        CutoffLPFLabel               matlab.ui.control.Label
        OrderLPFSlider               matlab.ui.control.Slider
        OrderSliderLabel             matlab.ui.control.Label
        OrderLPFLabel                matlab.ui.control.Label
        MetodeLPFDropDown            matlab.ui.control.DropDown
        MetodeDropDown_2Label        matlab.ui.control.Label
        SpatialDomainPanel           matlab.ui.container.Panel
        SigmaSpatialLabel            matlab.ui.control.Label
        SigmaSpatialSlider           matlab.ui.control.Slider
        KernelSizeSlider_2Label_2    matlab.ui.control.Label
        KernelSizeSpatialSlider      matlab.ui.control.Slider
        KernelSizeSlider_2Label      matlab.ui.control.Label
        KernelSizeSpatialLabel       matlab.ui.control.Label
        MetodeSpatialDropDown        matlab.ui.control.DropDown
        MetodeDropDownLabel          matlab.ui.control.Label
        DomainDropDown               matlab.ui.control.DropDown
        DomainDropDownLabel          matlab.ui.control.Label
        AddImageLPFButton            matlab.ui.control.Button
        ImgOutputLPFAxes             matlab.ui.control.UIAxes
        ImgOriLPFAxes                matlab.ui.control.UIAxes
        HighPassFilterTab            matlab.ui.container.Tab
        FrequencyDomainHPFPanel      matlab.ui.container.Panel
        CutoffHPFSlider              matlab.ui.control.Slider
        CutoffSliderLabel_2          matlab.ui.control.Label
        CutoffHPFLabel               matlab.ui.control.Label
        OrderHPFSlider               matlab.ui.control.Slider
        OrderSliderLabel_2           matlab.ui.control.Label
        OrderHPFLabel                matlab.ui.control.Label
        MetodeHPFDropDown            matlab.ui.control.DropDown
        MetodeDropDown_2Label_2      matlab.ui.control.Label
        AddImageHPFButton            matlab.ui.control.Button
        ImgOutputHPFAxes             matlab.ui.control.UIAxes
        ImgOriHPFAxes                matlab.ui.control.UIAxes
        HighBoostFilterTab           matlab.ui.container.Tab
        FrequencyDomainHBPFPanel     matlab.ui.container.Panel
        AHBPFLabel                   matlab.ui.control.Label
        AHBPFSlider                  matlab.ui.control.Slider
        AmplificationFactorLabel     matlab.ui.control.Label
        CutoffHBPFSlider             matlab.ui.control.Slider
        CutoffSliderLabel_3          matlab.ui.control.Label
        CutoffHBPFLabel              matlab.ui.control.Label
        OrderHBPFSlider              matlab.ui.control.Slider
        OrderSliderLabel_3           matlab.ui.control.Label
        OrderHBPFLabel               matlab.ui.control.Label
        MetodeHBPFDropDown           matlab.ui.control.DropDown
        MetodeDropDown_2Label_3      matlab.ui.control.Label
        AddImageHBPFButton           matlab.ui.control.Button
        ImgOutputHBPFAxes            matlab.ui.control.UIAxes
        ImgOriHBPFAxes               matlab.ui.control.UIAxes
        MedianFilterTab              matlab.ui.container.Tab
        WSSlider                     matlab.ui.control.Slider
        WindowSizeLabel              matlab.ui.control.Label
        WSLabel                      matlab.ui.control.Label
        DSlider                      matlab.ui.control.Slider
        NoiseDensityLabel            matlab.ui.control.Label
        DLabel                       matlab.ui.control.Label
        AddImageMFButton             matlab.ui.control.Button
        ImgNoiseMFAxes               matlab.ui.control.UIAxes
        ImgOutputMFAxes              matlab.ui.control.UIAxes
        ImgOriMFAxes                 matlab.ui.control.UIAxes
        NoiseFilteringTab            matlab.ui.container.Tab
        ApplyNFButton                matlab.ui.control.Button
        AddImageNFButton             matlab.ui.control.Button
        AddNotchFilterTextArea       matlab.ui.control.TextArea
        AddNotchFilterTextAreaLabel  matlab.ui.control.Label
        SpectrumNFAxes               matlab.ui.control.UIAxes
        ImgOutNFAxes                 matlab.ui.control.UIAxes
        ImgOriNFAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        im_conv = []; % Initial definition for image of Convolution tab
        im_lpf = []; % Initial definition for image of Low Pass Filter tab
        im_hpf = []; % Initial definition for image of High Pass Filter tab
        im_hbpf = []; % Initial definition for image of High Boost Pass Filter tab
        im_mf = []; % Initial definition for image of Median Filter tab
        im_sp = []; % Initial definition for noise image of Median Filter tab
        im_nf = []; % Initial definition for image of Notch Filter tab
    end
    
    methods (Access = public)
        % Function for notch filter
        function out_img = notch_filter(app, Img, filters)
            [F, ~] = spatial2frequency(app, Img);
            F = fftshift(F);
        
            n = size(filters, 1);
            for i = 1:n
                [x1, y1, x2, y2] = deal(filters(i, 1), filters(i, 2), filters(i, 3), filters(i, 4));
        
                F(x1:x2, y1:y2) = 0;
            end
        
            out_img = frequency2spatial(app, ifftshift(F));
        end

        % Create mean filter for spatial domain with nxn size
        function filter = create_mean_filter(~, n)
            filter = ones(n);
            filter = filter/sum(filter, "all");
        end
        
        % create gaussian filter for spatial domain with nxn size
        function filter = create_gaussian_filter(~, n, sigma)
            %// Generate horizontal and vertical co-ordinates, where
            %// the origin is in the middle
            ind = -floor(n/2) : floor(n/2);
            [X, Y] = meshgrid(ind, ind);
            
            %// Create Gaussian Mask
            h = (1 / (2 * pi * sigma ^ 2)) * exp(-(X.^2 + Y.^2) / (2*sigma*sigma));
            
            %// Normalize so that total area (sum of all weights) is 1
            filter = h / sum(h(:));
        end

        function out_img = pass_highboost_filter(app, Img, filter_type, D0, n, A)
            [M, N, channels] = size(Img);
            %Step 1: Tentukan parameter padding, biasanya untuk citra f(x,y)
            % berukuran M x N, parameter padding P dan Q adalah P = 2M and Q = 2N.
            P = 2*M;
            Q = 2*N;
            %Step 2: Bentuklah citra padding fp(x,y) berukuran P X Q dengan
            % menambahkan pixel-pixel bernilai nol pada f(x, y).
            % Proses padding dilakukan dengan menambahkan sebanyak M elemen 0 pada rows dan N
            % elemen 0 cols
            fp = padarray(Img, [M, N], 'post');
            %Step 3: Bangkitkan fungsi penapis H berukuran P x Q
            if strcmp(filter_type, 'hbilpf')
                H = A - ILPF(app, D0, P, Q);
            elseif strcmp(filter_type, 'hbglpf')
                H = A - GLPF(app, D0, P, Q);
            elseif strcmp(filter_type, 'hbblpf')
                H = A - BLPF(app, D0, n, P, Q);
            end
            % Untuk setiap channels warna
            out_img = zeros(M, N, channels);
            for c = 1:channels
                %Step 4: Lakukan transformasi Fourier pada fpad(x, y)
                [F, ~] = spatial2frequency(app, fp(:, :, c));
                %Step 5: Kalikan F dengan H
                G = H.*F;
                %Step 6: Ambil bagian real dari inverse FFT of G:
                g = frequency2spatial(app, G); % apply the inverse, discrete Fourier transform
                %Step 7: Potong bagian kiri atas sehingga menjadi berukuran citra semula
                out_img(:, :, c) = g(1:M, 1:N); % Resize the image to undo padding
            end
            out_img = uint8(out_img);
        end
        
        function out_img = pass_filter(app, Img, filter_type, D0, n)
            [M, N, channels] = size(Img);
            %Step 1: Tentukan parameter padding, biasanya untuk citra f(x,y)
            % berukuran M x N, parameter padding P dan Q adalah P = 2M and Q = 2N.
            P = 2*M;
            Q = 2*N;
            %Step 2: Bentuklah citra padding fp(x,y) berukuran P X Q dengan
            % menambahkan pixel-pixel bernilai nol pada f(x, y).
            % Proses padding dilakukan dengan menambahkan sebanyak M elemen 0 pada rows dan N
            % elemen 0 cols
            fp = padarray(Img, [M, N], 'post');
            %Step 3: Bangkitkan fungsi penapis H berukuran P x Q
            if strcmp(filter_type, 'ilpf')
                H = ILPF(app, D0, P, Q);
            elseif strcmp(filter_type, 'glpf')
                H = GLPF(app, D0, P, Q);
            elseif strcmp(filter_type, 'blpf')
                H = BLPF(app, D0, n, P, Q);
            elseif strcmp(filter_type, 'ihpf')
                H = IHPF(app, D0, P, Q);
            elseif strcmp(filter_type, 'ghpf')
                H = GHPF(app, D0, P, Q);
            elseif strcmp(filter_type, 'bhpf')
                H = BHPF(app, D0, n, P, Q);
            end
            % Untuk setiap channels warna
            out_img = zeros(M, N, channels);
            for c = 1:channels
                %Step 4: Lakukan transformasi Fourier pada fpad(x, y)
                [F, ~] = spatial2frequency(app, fp(:, :, c));
                %Step 5: Kalikan F dengan H
                G = H.*F;
                %Step 6: Ambil bagian real dari inverse FFT of G:
                g = frequency2spatial(app, G); % apply the inverse, discrete Fourier transform
                %Step 7: Potong bagian kiri atas sehingga menjadi berukuran citra semula
                out_img(:, :, c) = g(1:M, 1:N); % Resize the image to undo padding
            end
            out_img = uint8(out_img);
        end
        
        function H = BHPF(app, D0, n, P, Q)
            H = 1 - BLPF(app, D0, n, P, Q);
        end
        
        function H = GHPF(app, D0, P, Q)
            H = 1 - GLPF(app, D0, P, Q);
        end
        
        function H = IHPF(app, D0, P, Q)
            H = 1 - ILPF(app, D0, P, Q);
        end
        
        function H = BLPF(app, D0, n, P, Q)
            D = designing_filter(app, P, Q);
            H = 1./(1 + (D./D0).^(2*n));
        end
        
        function H = GLPF(app, D0, P, Q)
            D = designing_filter(app, P, Q);
            H = exp(-(D.^2)./(2*(D0^2)));
        end
        
        function H = ILPF(app, D0, P, Q)
            D = designing_filter(app, P, Q);
            H = double(D <= D0);
        end
        
        function D = designing_filter(~, P, Q)
            % Set up range of variables.
            u = 0:(P-1);
            v = 0:(Q-1);
            % Compute the indices for use in meshgrid
            idx = find(u > P/2);
            u(idx) = u(idx) - P;
            idy = find(v > Q/2);
            v(idy) = v(idy) - Q;
            % Compute the meshgrid arrays
            % MATLAB library function meshgrid(v, u) returns
            % 2D grid which contains the coordinates of vectors
            % v and u. Matrix V with each row is a copy 
            % of v, and matrix U with each column is a copy of u
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2 + V.^2);
        end
        
        function [ft_img, fs_img] = spatial2frequency(~, Img)
            ft_img = fft2(double(Img));
            fs_img = uint8(log(1+abs(fftshift(ft_img))));
        end
        
        function Img = frequency2spatial(~, ft_img)
            Img = uint8(real(ifft2(ft_img)));
        end
        
        function Img = padsameimg(~, Img, kernel_height, kernel_width)
            two_padsize_h = kernel_height - 1;
            two_padsize_w = kernel_width - 1;
            if mod(two_padsize_h, 2) == 1 && mod(two_padsize_w, 2) == 1
                padsize_h_1 = floor(two_padsize_h/2);
                padsize_h_2 = floor(two_padsize_h/2) + 1;
                padsize_w_1 = floor(two_padsize_w/2);
                padsize_w_2 = floor(two_padsize_w/2) + 1;
                Img = double(padarray(Img, [padsize_h_1, padsize_w_1], 0, 'pre'));
                Img = double(padarray(Img, [padsize_h_2, padsize_w_2], 0, 'post'));
            elseif mod(two_padsize_h, 2) == 1 && mod(two_padsize_w, 2) == 0
                padsize_h_1 = floor(two_padsize_h/2);
                padsize_h_2 = floor(two_padsize_h/2) + 1;
                padsize_w_1 = two_padsize_w/2;
                padsize_w_2 = two_padsize_w/2;
                Img = double(padarray(Img, [padsize_h_1, padsize_w_1], 0, 'pre'));
                Img = double(padarray(Img, [padsize_h_2, padsize_w_2], 0, 'post'));
            elseif mod(two_padsize_h, 2) == 0 && mod(two_padsize_w, 2) == 1
                padsize_h_1 = two_padsize_h/2;
                padsize_h_2 = two_padsize_h/2;
                padsize_w_1 = floor(two_padsize_w/2);
                padsize_w_2 = floor(two_padsize_w/2) + 1;
                Img = double(padarray(Img, [padsize_h_1, padsize_w_1], 0, 'pre'));
                Img = double(padarray(Img, [padsize_h_2, padsize_w_2], 0, 'post'));
            elseif mod(two_padsize_h, 2) == 0 && mod(two_padsize_w, 2) == 0
                padsize_h_1 = two_padsize_h/2;
                padsize_h_2 = two_padsize_h/2;
                padsize_w_1 = two_padsize_w/2;
                padsize_w_2 = two_padsize_w/2;
                Img = double(padarray(Img, [padsize_h_1, padsize_w_1], 0, 'pre'));
                Img = double(padarray(Img, [padsize_h_2, padsize_w_2], 0, 'post'));
            end
        end
        
        % Same padding, output image shape = input image shape
        function conv_img = conv2d(app, Img, kernel)
            Img = double(Img);
            [kernel_height, kernel_width] = size(kernel);
            Img = padsameimg(app, Img, kernel_height, kernel_width);
            [height, width, channels] = size(Img);
            [output_height, output_width] = deal(height - kernel_height + 1, width - kernel_width + 1);
            conv_img = zeros(output_height, output_width, channels);
            for c = 1:channels
                conv_img(:, :, c) = conv_operation(app, Img(:, :, c), kernel);
            end
            conv_img = uint8(conv_img);
        end
        
        function conv_img = conv_operation(~, mat, kernel)
            [height, width] = size(mat);
            [kernel_height, kernel_width] = size(kernel);
            [output_height, output_width] = deal(height - kernel_height + 1, width - kernel_width + 1);
            conv_img = zeros(output_height, output_width);
            for i = 1:output_height
                for j = 1:output_width
                    curwindow = mat(i:i+kernel_height-1, j:j+kernel_width-1);
                    conv_img(i, j) = sum(curwindow .* kernel, "all");
                end
            end
        end
        
        % Same padding, output image shape = input image shape
        function medfil_img = medfil(app, Img, window_size)
            Img = double(Img);
            [window_height, window_width] = deal(window_size(1), window_size(2));
            Img = padsameimg(app, Img, window_height, window_width);
            [height, width, channels] = size(Img);
            [output_height, output_width] = deal(height - window_height + 1, width - window_width + 1);
            medfil_img = zeros(output_height, output_width, channels);
            for c = 1:channels
                medfil_img(:, :, c) = medfil_operation(app, Img(:, :, c), window_size);
            end
            medfil_img = uint8(medfil_img);
        end
        
        function medfil_img = medfil_operation(~, mat, window_size)
            [height, width] = size(mat);
            [window_height, window_width] = deal(window_size(1), window_size(2));
            [output_height, output_width] = deal(height - window_height + 1, width - window_width + 1);
            medfil_img = zeros(output_height, output_width);
            for i = 1:output_height
                for j = 1:output_width
                    curwindow = mat(i:i+window_height-1, j:j+window_width-1);
                    medfil_img(i, j) = median(curwindow, "all");
                end
            end
        end

        function DisplayImageLPF(app)
            if isempty(app.im_lpf) == 0
                if strcmp(app.DomainDropDown.Value, 'Spatial')
                    n = round(app.KernelSizeSpatialSlider.Value);
                    if strcmp(app.MetodeSpatialDropDown.Value, 'Mean Filter')
                        filter = create_mean_filter(app, n);
                    elseif strcmp(app.MetodeSpatialDropDown.Value, 'Gaussian Filter')
                        filter = create_gaussian_filter(app, n, round(app.SigmaSpatialSlider.Value));
                    end
                    im_out = conv2d(app, app.im_lpf, filter);
                elseif strcmp(app.DomainDropDown.Value, 'Frequency')
                    if strcmp(app.MetodeLPFDropDown.Value, 'Ideal')
                        im_out = pass_filter(app, app.im_lpf, 'ilpf', round(app.CutoffLPFSlider.Value, 2), round(app.OrderLPFSlider.Value));
                    elseif strcmp(app.MetodeLPFDropDown.Value, 'Gaussian')
                        im_out = pass_filter(app, app.im_lpf, 'glpf', round(app.CutoffLPFSlider.Value, 2), round(app.OrderLPFSlider.Value));
                    elseif strcmp(app.MetodeLPFDropDown.Value, 'Butterworth')
                        im_out = pass_filter(app, app.im_lpf, 'blpf', round(app.CutoffLPFSlider.Value, 2), round(app.OrderLPFSlider.Value));
                    end
                end
                imshow(im_out, 'Parent', app.ImgOutputLPFAxes);
                app.ImgOutputLPFAxes.XLim = [0 width(im_out)];
                app.ImgOutputLPFAxes.YLim = [0 height(im_out)];
            end
        end

        function DisplayImageHPF(app)
            if isempty(app.im_hpf) == 0
                if strcmp(app.MetodeHPFDropDown.Value, 'Ideal')
                    im_out = pass_filter(app, app.im_hpf, 'ihpf', round(app.CutoffHPFSlider.Value, 2), round(app.OrderHPFSlider.Value));
                elseif strcmp(app.MetodeHPFDropDown.Value, 'Gaussian')
                    im_out = pass_filter(app, app.im_hpf, 'ghpf', round(app.CutoffHPFSlider.Value, 2), round(app.OrderHPFSlider.Value));
                elseif strcmp(app.MetodeHPFDropDown.Value, 'Butterworth')
                    im_out = pass_filter(app, app.im_hpf, 'bhpf', round(app.CutoffHPFSlider.Value, 2), round(app.OrderHPFSlider.Value));
                end
                imshow(im_out, 'Parent', app.ImgOutputHPFAxes);
                app.ImgOutputHPFAxes.XLim = [0 width(im_out)];
                app.ImgOutputHPFAxes.YLim = [0 height(im_out)];
            end
        end

        function DisplayImageHBPF(app)
            if isempty(app.im_hbpf) == 0
                if strcmp(app.MetodeHBPFDropDown.Value, 'Ideal')
                    im_out = pass_highboost_filter(app, app.im_hbpf, 'hbilpf', round(app.CutoffHBPFSlider.Value, 2), round(app.OrderHBPFSlider.Value), round(app.AHBPFSlider.Value, 1));
                elseif strcmp(app.MetodeHBPFDropDown.Value, 'Gaussian')
                    im_out = pass_highboost_filter(app, app.im_hbpf, 'hbglpf', round(app.CutoffHBPFSlider.Value, 2), round(app.OrderHBPFSlider.Value), round(app.AHBPFSlider.Value, 1));
                elseif strcmp(app.MetodeHBPFDropDown.Value, 'Butterworth')
                    im_out = pass_highboost_filter(app, app.im_hbpf, 'hbblpf', round(app.CutoffHBPFSlider.Value, 2), round(app.OrderHBPFSlider.Value), round(app.AHBPFSlider.Value, 1));
                end
                imshow(im_out, 'Parent', app.ImgOutputHBPFAxes);
                app.ImgOutputHBPFAxes.XLim = [0 width(im_out)];
                app.ImgOutputHBPFAxes.YLim = [0 height(im_out)];
            end
        end

        function AddSaltnPepper(app)
            if isempty(app.im_mf) == 0
                d = round(app.DSlider.Value, 1);
                ws = round(app.WSSlider.Value);
                app.im_sp = imnoise(app.im_mf, "salt & pepper", d);
                imshow(app.im_sp, 'Parent', app.ImgNoiseMFAxes);
                app.ImgNoiseMFAxes.XLim = [0 width(app.im_sp)];
                app.ImgNoiseMFAxes.YLim = [0 height(app.im_sp)];
                im_out = medfil(app, app.im_sp, [ws ws]);
                imshow(im_out, 'Parent', app.ImgOutputMFAxes);
                app.ImgOutputMFAxes.XLim = [0 width(im_out)];
                app.ImgOutputMFAxes.YLim = [0 height(im_out)];
            end
        end

        function applyMF(app)
            if isempty(app.im_mf) == 0
                ws = round(app.WSSlider.Value);
                im_out = medfil(app, app.im_sp, [ws ws]);
                imshow(im_out, 'Parent', app.ImgOutputMFAxes);
                app.ImgOutputMFAxes.XLim = [0 width(im_out)];
                app.ImgOutputMFAxes.YLim = [0 height(im_out)];
            end
        end

        function DisplayImageNF(app)
            if isempty(app.im_nf) == 0
                filters_value = app.AddNotchFilterTextArea.Value;
                str = '';
                for i = 1:size(filters_value, 1)
                    str = strcat(str, filters_value{i, 1});
                end
                filters = str2num(str);
                im_out = notch_filter(app, app.im_nf, filters);
                imshow(im_out, 'Parent', app.ImgOutNFAxes);
                app.ImgOutNFAxes.XLim = [0 width(im_out)];
                app.ImgOutNFAxes.YLim = [0 height(im_out)];
                [~, s] = spatial2frequency(app, im_out);
                imshow(s, [], 'Parent', app.SpectrumNFAxes);
                app.SpectrumNFAxes.XLim = [0 width(s)];
                app.SpectrumNFAxes.YLim = [0 height(s)];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddImageConvolutionButton
        function AddImageConvolutionButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp;*.oct;*.tif;*.jpeg'}, 'Select Citra Input');
            file = [path, file];
            app.im_conv = imread(file);
            imshow(app.im_conv, 'Parent', app.ImgOriAxes);
            app.ImgOriAxes.XLim = [0 width(app.im_conv)];
            app.ImgOriAxes.YLim = [0 height(app.im_conv)];
            kernel = randn(round(app.KernelSizeSlider.Value));
            kernel = kernel / sum(kernel, "all");
            im_out = conv2d(app, app.im_conv, kernel);
            imshow(im_out, 'Parent', app.ImgConvAxes);
            app.ImgConvAxes.XLim = [0 width(im_out)];
            app.ImgConvAxes.YLim = [0 height(im_out)];
        end

        % Value changing function: KernelSizeSlider
        function KernelSizeSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.KernelSizeLabel.Text = string(changingValue);
        end

        % Button pushed function: RandomKernelButton
        function RandomKernelButtonPushed(app, event)
            kernel = randn(round(app.KernelSizeSlider.Value));
            kernel = kernel / sum(kernel, "all");
            im_out = conv2d(app, app.im_conv, kernel);
            imshow(im_out, 'Parent', app.ImgConvAxes);
            app.ImgConvAxes.XLim = [0 width(im_out)];
            app.ImgConvAxes.YLim = [0 height(im_out)];
        end

        % Value changed function: DomainDropDown
        function DomainDropDownValueChanged(app, event)
            value = app.DomainDropDown.Value;
            if strcmp(value, 'Spatial')
                set(app.SpatialDomainPanel, 'Visible', 'on');
                set(app.FrequencyDomainPanel, 'Visible', 'off');
            elseif strcmp(value, 'Frequency')
                set(app.SpatialDomainPanel, 'Visible', 'off');
                set(app.FrequencyDomainPanel, 'Visible', 'on');
            end
            app.DisplayImageLPF();
        end

        % Value changed function: MetodeLPFDropDown
        function MetodeLPFDropDownValueChanged(app, event)
            value = app.MetodeLPFDropDown.Value;
            if strcmp(value, 'Butterworth')
                set(app.OrderLPFSlider, 'Enable', 'on');
            else
                set(app.OrderLPFSlider, 'Enable', 'off');
            end
            app.DisplayImageLPF();
        end

        % Value changing function: KernelSizeSpatialSlider
        function KernelSizeSpatialSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.KernelSizeSpatialLabel.Text = string(changingValue);
        end

        % Button pushed function: AddImageLPFButton
        function AddImageLPFButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp;*.oct;*.tif;*.jpeg'}, 'Select Citra Input');
            file = [path, file];
            app.im_lpf = imread(file);
            imshow(app.im_lpf, 'Parent', app.ImgOriLPFAxes);
            app.ImgOriLPFAxes.XLim = [0 width(app.im_lpf)];
            app.ImgOriLPFAxes.YLim = [0 height(app.im_lpf)];
            app.DisplayImageLPF();
        end

        % Value changed function: MetodeSpatialDropDown
        function MetodeSpatialDropDownValueChanged(app, event)
            value = app.MetodeSpatialDropDown.Value;
            if strcmp(value, 'Gaussian Filter')
                set(app.SigmaSpatialSlider, 'Enable', 'on');
            else
                set(app.SigmaSpatialSlider, 'Enable', 'off');
            end
            app.DisplayImageLPF();
        end

        % Value changed function: KernelSizeSpatialSlider
        function KernelSizeSpatialSliderValueChanged(app, event)
            app.DisplayImageLPF()
        end

        % Value changing function: SigmaSpatialSlider
        function SigmaSpatialSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.SigmaSpatialLabel.Text = string(changingValue);
        end

        % Value changed function: SigmaSpatialSlider
        function SigmaSpatialSliderValueChanged(app, event)
            app.DisplayImageLPF();
        end

        % Value changing function: OrderLPFSlider
        function OrderLPFSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.OrderLPFLabel.Text = string(changingValue);
        end

        % Value changing function: CutoffLPFSlider
        function CutoffLPFSliderValueChanging(app, event)
            changingValue = round(event.Value, 2);
            app.CutoffLPFLabel.Text = string(changingValue);
        end

        % Value changed function: OrderLPFSlider
        function OrderLPFSliderValueChanged(app, event)
            app.DisplayImageLPF();
        end

        % Value changed function: CutoffLPFSlider
        function CutoffLPFSliderValueChanged(app, event)
            app.DisplayImageLPF()
        end

        % Button pushed function: AddImageHPFButton
        function AddImageHPFButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp;*.oct;*.tif;*.jpeg'}, 'Select Citra Input');
            file = [path, file];
            app.im_hpf = imread(file);
            imshow(app.im_hpf, 'Parent', app.ImgOriHPFAxes);
            app.ImgOriHPFAxes.XLim = [0 width(app.im_hpf)];
            app.ImgOriHPFAxes.YLim = [0 height(app.im_hpf)];
            app.DisplayImageHPF();
        end

        % Value changed function: MetodeHPFDropDown
        function MetodeHPFDropDownValueChanged(app, event)
            value = app.MetodeHPFDropDown.Value;
            if strcmp(value, 'Butterworth')
                set(app.OrderHPFSlider, 'Enable', 'on');
            else
                set(app.OrderHPFSlider, 'Enable', 'off');
            end
            app.DisplayImageHPF();
        end

        % Value changing function: OrderHPFSlider
        function OrderHPFSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.OrderHPFLabel.Text = string(changingValue);
        end

        % Value changing function: CutoffHPFSlider
        function CutoffHPFSliderValueChanging(app, event)
            changingValue = round(event.Value, 2);
            app.CutoffHPFLabel.Text = string(changingValue);
        end

        % Value changed function: OrderHPFSlider
        function OrderHPFSliderValueChanged(app, event)
            app.DisplayImageHPF();
        end

        % Value changed function: CutoffHPFSlider
        function CutoffHPFSliderValueChanged(app, event)
            app.DisplayImageHPF();
        end

        % Button pushed function: AddImageHBPFButton
        function AddImageHBPFButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp;*.oct;*.tif;*.jpeg'}, 'Select Citra Input');
            file = [path, file];
            app.im_hbpf = imread(file);
            imshow(app.im_hbpf, 'Parent', app.ImgOriHBPFAxes);
            app.ImgOriHBPFAxes.XLim = [0 width(app.im_hbpf)];
            app.ImgOriHBPFAxes.YLim = [0 height(app.im_hbpf)];
            app.DisplayImageHBPF();
        end

        % Value changed function: MetodeHBPFDropDown
        function MetodeHBPFDropDownValueChanged(app, event)
            value = app.MetodeHBPFDropDown.Value;
            if strcmp(value, 'Butterworth')
                set(app.OrderHBPFSlider, 'Enable', 'on');
            else
                set(app.OrderHBPFSlider, 'Enable', 'off');
            end
            app.DisplayImageHBPF();
        end

        % Value changing function: OrderHBPFSlider
        function OrderHBPFSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.OrderHBPFLabel.Text = string(changingValue);
        end

        % Value changed function: OrderHBPFSlider
        function OrderHBPFSliderValueChanged(app, event)
            app.DisplayImageHBPF();
        end

        % Value changing function: CutoffHBPFSlider
        function CutoffHBPFSliderValueChanging(app, event)
            changingValue = round(event.Value, 2);
            app.CutoffHBPFLabel.Text = string(changingValue);
        end

        % Value changed function: CutoffHBPFSlider
        function CutoffHBPFSliderValueChanged(app, event)
            app.DisplayImageHBPF();
        end

        % Value changing function: AHBPFSlider
        function AHBPFSliderValueChanging(app, event)
            changingValue = round(event.Value, 1);
            app.AHBPFLabel.Text = string(changingValue);
        end

        % Value changed function: AHBPFSlider
        function AHBPFSliderValueChanged(app, event)
            app.DisplayImageHBPF();
        end

        % Button pushed function: AddImageMFButton
        function AddImageMFButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp;*.oct;*.tif;*.jpeg'}, 'Select Citra Input');
            file = [path, file];
            app.im_mf = imread(file);
            imshow(app.im_mf, 'Parent', app.ImgOriMFAxes);
            app.ImgOriMFAxes.XLim = [0 width(app.im_mf)];
            app.ImgOriMFAxes.YLim = [0 height(app.im_mf)];
            app.AddSaltnPepper();
        end

        % Value changing function: DSlider
        function DSliderValueChanging(app, event)
            changingValue = round(event.Value, 1);
            app.DLabel.Text = string(changingValue);
        end

        % Value changing function: WSSlider
        function WSSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.WSLabel.Text = string(changingValue);
        end

        % Value changed function: WSSlider
        function WSSliderValueChanged(app, event)
            app.applyMF();
        end

        % Value changed function: DSlider
        function DSliderValueChanged(app, event)
            app.AddSaltnPepper();
        end

        % Button pushed function: AddImageNFButton
        function AddImageNFButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp;*.oct;*.tif;*.jpeg'}, 'Select Citra Input');
            file = [path, file];
            img = imread(file);
            if size(img, 3) ~= 1
                img = rgb2gray(img);
            end
            app.im_nf = img;
            imshow(app.im_nf, 'Parent', app.ImgOriNFAxes);
            app.ImgOriNFAxes.XLim = [0 width(app.im_nf)];
            app.ImgOriNFAxes.YLim = [0 height(app.im_nf)];
            app.DisplayImageNF();
        end

        % Button pushed function: ApplyNFButton
        function ApplyNFButtonPushed(app, event)
            app.DisplayImageNF();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConvolutionTab = uitab(app.TabGroup);
            app.ConvolutionTab.Title = 'Convolution';
            app.ImgConvAxes = uiaxes(app.ConvolutionTab);
            title(app.ImgConvAxes, 'Image Hasil Konvolusi')
            app.ImgOriAxes = uiaxes(app.ConvolutionTab);
            title(app.ImgOriAxes, 'Image Input')
            app.AddImageConvolutionButton = uibutton(app.ConvolutionTab, 'push');
            app.AddImageConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageConvolutionButtonPushed, true);
            app.AddImageConvolutionButton.Text = 'Add Image';
            app.RandomKernelButton = uibutton(app.ConvolutionTab, 'push');
            app.RandomKernelButton.ButtonPushedFcn = createCallbackFcn(app, @RandomKernelButtonPushed, true);
            app.RandomKernelButton.Text = 'Random Kernel';
            app.KernelSizeSliderLabel = uilabel(app.ConvolutionTab);
            app.KernelSizeSliderLabel.Text = 'Kernel Size';
            app.KernelSizeSlider = uislider(app.ConvolutionTab);
            app.KernelSizeSlider.Limits = [1 50];
            app.KernelSizeSlider.ValueChangingFcn = createCallbackFcn(app, @KernelSizeSliderValueChanging, true);
            app.KernelSizeSlider.Value = 3;
            app.KernelSizeLabel = uilabel(app.ConvolutionTab);
            app.KernelSizeLabel.Text = '3';
            app.LowPassFilterTab = uitab(app.TabGroup);
            app.LowPassFilterTab.Title = 'Low-Pass Filter';
            app.ImgOriLPFAxes = uiaxes(app.LowPassFilterTab);
            title(app.ImgOriLPFAxes, 'Image Input')
            app.ImgOutputLPFAxes = uiaxes(app.LowPassFilterTab);
            title(app.ImgOutputLPFAxes, 'Image Hasil Filtering')
            app.AddImageLPFButton = uibutton(app.LowPassFilterTab, 'push');
            app.AddImageLPFButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageLPFButtonPushed, true);
            app.AddImageLPFButton.Text = 'Add Image';
            app.DomainDropDownLabel = uilabel(app.LowPassFilterTab);
            app.DomainDropDownLabel.Text = 'Domain';
            app.DomainDropDown = uidropdown(app.LowPassFilterTab);
            app.DomainDropDown.Items = {'Spatial', 'Frequency'};
            app.DomainDropDown.ValueChangedFcn = createCallbackFcn(app, @DomainDropDownValueChanged, true);
            app.DomainDropDown.Value = 'Spatial';
            app.SpatialDomainPanel = uipanel(app.LowPassFilterTab);
            app.SpatialDomainPanel.Title = 'Spatial Domain';
            app.MetodeDropDownLabel = uilabel(app.SpatialDomainPanel);
            app.MetodeDropDownLabel.Text = 'Metode';
            app.MetodeSpatialDropDown = uidropdown(app.SpatialDomainPanel);
            app.MetodeSpatialDropDown.Items = {'Mean Filter', 'Gaussian Filter'};
            app.MetodeSpatialDropDown.ValueChangedFcn = createCallbackFcn(app, @MetodeSpatialDropDownValueChanged, true);
            app.MetodeSpatialDropDown.Value = 'Mean Filter';
            app.KernelSizeSpatialLabel = uilabel(app.SpatialDomainPanel);
            app.KernelSizeSpatialLabel.Text = '3';
            app.KernelSizeSlider_2Label = uilabel(app.SpatialDomainPanel);
            app.KernelSizeSlider_2Label.Text = 'Kernel Size';
            app.KernelSizeSpatialSlider = uislider(app.SpatialDomainPanel);
            app.KernelSizeSpatialSlider.Limits = [1 50];
            app.KernelSizeSpatialSlider.ValueChangedFcn = createCallbackFcn(app, @KernelSizeSpatialSliderValueChanged, true);
            app.KernelSizeSpatialSlider.ValueChangingFcn = createCallbackFcn(app, @KernelSizeSpatialSliderValueChanging, true);
            app.KernelSizeSpatialSlider.Value = 3;
            app.KernelSizeSlider_2Label_2 = uilabel(app.SpatialDomainPanel);
            app.KernelSizeSlider_2Label_2.Text = 'Sigma';
            app.SigmaSpatialSlider = uislider(app.SpatialDomainPanel);
            app.SigmaSpatialSlider.Limits = [1 10];
            app.SigmaSpatialSlider.ValueChangedFcn = createCallbackFcn(app, @SigmaSpatialSliderValueChanged, true);
            app.SigmaSpatialSlider.ValueChangingFcn = createCallbackFcn(app, @SigmaSpatialSliderValueChanging, true);
            app.SigmaSpatialSlider.Enable = 'off';
            app.SigmaSpatialSlider.Value = 1;
            app.SigmaSpatialLabel = uilabel(app.SpatialDomainPanel);
            app.SigmaSpatialLabel.Text = '3';
            app.FrequencyDomainPanel = uipanel(app.LowPassFilterTab);
            app.FrequencyDomainPanel.Title = 'Frequency Domain';
            app.FrequencyDomainPanel.Visible = 'off';
            app.MetodeDropDown_2Label = uilabel(app.FrequencyDomainPanel);
            app.MetodeDropDown_2Label.Text = 'Metode';
            app.MetodeLPFDropDown = uidropdown(app.FrequencyDomainPanel);
            app.MetodeLPFDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.MetodeLPFDropDown.ValueChangedFcn = createCallbackFcn(app, @MetodeLPFDropDownValueChanged, true);
            app.MetodeLPFDropDown.Value = 'Ideal';
            app.OrderLPFLabel = uilabel(app.FrequencyDomainPanel);
            app.OrderLPFLabel.Text = '2';
            app.OrderSliderLabel = uilabel(app.FrequencyDomainPanel);
            app.OrderSliderLabel.Text = 'Order';
            app.OrderLPFSlider = uislider(app.FrequencyDomainPanel);
            app.OrderLPFSlider.Limits = [0 10];
            app.OrderLPFSlider.ValueChangedFcn = createCallbackFcn(app, @OrderLPFSliderValueChanged, true);
            app.OrderLPFSlider.ValueChangingFcn = createCallbackFcn(app, @OrderLPFSliderValueChanging, true);
            app.OrderLPFSlider.Enable = 'off';
            app.OrderLPFSlider.Value = 2;
            app.CutoffLPFLabel = uilabel(app.FrequencyDomainPanel);
            app.CutoffLPFLabel.Text = '50';
            app.CutoffSliderLabel = uilabel(app.FrequencyDomainPanel);
            app.CutoffSliderLabel.Text = 'Cutoff';
            app.CutoffLPFSlider = uislider(app.FrequencyDomainPanel);
            app.CutoffLPFSlider.Limits = [0 200];
            app.CutoffLPFSlider.ValueChangedFcn = createCallbackFcn(app, @CutoffLPFSliderValueChanged, true);
            app.CutoffLPFSlider.ValueChangingFcn = createCallbackFcn(app, @CutoffLPFSliderValueChanging, true);
            app.CutoffLPFSlider.Value = 50;
            app.HighPassFilterTab = uitab(app.TabGroup);
            app.HighPassFilterTab.Title = 'High-Pass Filter';
            app.ImgOriHPFAxes = uiaxes(app.HighPassFilterTab);
            title(app.ImgOriHPFAxes, 'Image Input')
            app.ImgOutputHPFAxes = uiaxes(app.HighPassFilterTab);
            title(app.ImgOutputHPFAxes, 'Image Hasil Filtering')
            app.AddImageHPFButton = uibutton(app.HighPassFilterTab, 'push');
            app.AddImageHPFButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageHPFButtonPushed, true);
            app.AddImageHPFButton.Text = 'Add Image';
            app.FrequencyDomainHPFPanel = uipanel(app.HighPassFilterTab);
            app.FrequencyDomainHPFPanel.Title = 'Frequency Domain';
            app.MetodeDropDown_2Label_2 = uilabel(app.FrequencyDomainHPFPanel);
            app.MetodeDropDown_2Label_2.Text = 'Metode';
            app.MetodeHPFDropDown = uidropdown(app.FrequencyDomainHPFPanel);
            app.MetodeHPFDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.MetodeHPFDropDown.ValueChangedFcn = createCallbackFcn(app, @MetodeHPFDropDownValueChanged, true);
            app.MetodeHPFDropDown.Value = 'Ideal';
            app.OrderHPFLabel = uilabel(app.FrequencyDomainHPFPanel);
            app.OrderHPFLabel.Text = '2';
            app.OrderSliderLabel_2 = uilabel(app.FrequencyDomainHPFPanel);
            app.OrderSliderLabel_2.Text = 'Order';
            app.OrderHPFSlider = uislider(app.FrequencyDomainHPFPanel);
            app.OrderHPFSlider.Limits = [0 10];
            app.OrderHPFSlider.ValueChangedFcn = createCallbackFcn(app, @OrderHPFSliderValueChanged, true);
            app.OrderHPFSlider.ValueChangingFcn = createCallbackFcn(app, @OrderHPFSliderValueChanging, true);
            app.OrderHPFSlider.Enable = 'off';
            app.OrderHPFSlider.Value = 2;
            app.CutoffHPFLabel = uilabel(app.FrequencyDomainHPFPanel);
            app.CutoffHPFLabel.Text = '50';
            app.CutoffSliderLabel_2 = uilabel(app.FrequencyDomainHPFPanel);
            app.CutoffSliderLabel_2.Text = 'Cutoff';
            app.CutoffHPFSlider = uislider(app.FrequencyDomainHPFPanel);
            app.CutoffHPFSlider.Limits = [0 200];
            app.CutoffHPFSlider.ValueChangedFcn = createCallbackFcn(app, @CutoffHPFSliderValueChanged, true);
            app.CutoffHPFSlider.ValueChangingFcn = createCallbackFcn(app, @CutoffHPFSliderValueChanging, true);
            app.CutoffHPFSlider.Value = 50;
            app.HighBoostFilterTab = uitab(app.TabGroup);
            app.HighBoostFilterTab.Title = 'High-Boost Filter';
            app.ImgOriHBPFAxes = uiaxes(app.HighBoostFilterTab);
            title(app.ImgOriHBPFAxes, 'Image Input')
            app.ImgOutputHBPFAxes = uiaxes(app.HighBoostFilterTab);
            title(app.ImgOutputHBPFAxes, 'Image Hasil Filtering')
            app.AddImageHBPFButton = uibutton(app.HighBoostFilterTab, 'push');
            app.AddImageHBPFButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageHBPFButtonPushed, true);
            app.AddImageHBPFButton.Text = 'Add Image';
            app.FrequencyDomainHBPFPanel = uipanel(app.HighBoostFilterTab);
            app.FrequencyDomainHBPFPanel.Title = 'Frequency Domain';
            app.MetodeDropDown_2Label_3 = uilabel(app.FrequencyDomainHBPFPanel);
            app.MetodeDropDown_2Label_3.Text = 'Metode';
            app.MetodeHBPFDropDown = uidropdown(app.FrequencyDomainHBPFPanel);
            app.MetodeHBPFDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.MetodeHBPFDropDown.ValueChangedFcn = createCallbackFcn(app, @MetodeHBPFDropDownValueChanged, true);
            app.MetodeHBPFDropDown.Value = 'Ideal';
            app.OrderHBPFLabel = uilabel(app.FrequencyDomainHBPFPanel);
            app.OrderHBPFLabel.Text = '2';
            app.OrderSliderLabel_3 = uilabel(app.FrequencyDomainHBPFPanel);
            app.OrderSliderLabel_3.Text = 'Order';
            app.OrderHBPFSlider = uislider(app.FrequencyDomainHBPFPanel);
            app.OrderHBPFSlider.Limits = [0 10];
            app.OrderHBPFSlider.ValueChangedFcn = createCallbackFcn(app, @OrderHBPFSliderValueChanged, true);
            app.OrderHBPFSlider.ValueChangingFcn = createCallbackFcn(app, @OrderHBPFSliderValueChanging, true);
            app.OrderHBPFSlider.Enable = 'off';
            app.OrderHBPFSlider.Value = 2;
            app.CutoffHBPFLabel = uilabel(app.FrequencyDomainHBPFPanel);
            app.CutoffHBPFLabel.Text = '50';
            app.CutoffSliderLabel_3 = uilabel(app.FrequencyDomainHBPFPanel);
            app.CutoffSliderLabel_3.Text = 'Cutoff';
            app.CutoffHBPFSlider = uislider(app.FrequencyDomainHBPFPanel);
            app.CutoffHBPFSlider.Limits = [0 200];
            app.CutoffHBPFSlider.ValueChangedFcn = createCallbackFcn(app, @CutoffHBPFSliderValueChanged, true);
            app.CutoffHBPFSlider.ValueChangingFcn = createCallbackFcn(app, @CutoffHBPFSliderValueChanging, true);
            app.CutoffHBPFSlider.Value = 50;
            app.AmplificationFactorLabel = uilabel(app.FrequencyDomainHBPFPanel);
            app.AmplificationFactorLabel.Text = 'Amplification Factor';
            app.AHBPFSlider = uislider(app.FrequencyDomainHBPFPanel);
            app.AHBPFSlider.Limits = [1 5];
            app.AHBPFSlider.ValueChangedFcn = createCallbackFcn(app, @AHBPFSliderValueChanged, true);
            app.AHBPFSlider.ValueChangingFcn = createCallbackFcn(app, @AHBPFSliderValueChanging, true);
            app.AHBPFSlider.Value = 2.4;
            app.AHBPFLabel = uilabel(app.FrequencyDomainHBPFPanel);
            app.AHBPFLabel.Text = '2.4';
            app.MedianFilterTab = uitab(app.TabGroup);
            app.MedianFilterTab.Title = 'Median Filter';
            app.ImgOriMFAxes = uiaxes(app.MedianFilterTab);
            title(app.ImgOriMFAxes, 'Image Input')
            app.ImgOutputMFAxes = uiaxes(app.MedianFilterTab);
            title(app.ImgOutputMFAxes, 'Image Hasil Filtering')
            app.ImgNoiseMFAxes = uiaxes(app.MedianFilterTab);
            title(app.ImgNoiseMFAxes, 'Penambahan Noise Salt & Pepper')
            app.AddImageMFButton = uibutton(app.MedianFilterTab, 'push');
            app.AddImageMFButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageMFButtonPushed, true);
            app.AddImageMFButton.Text = 'Add Image';
            app.DLabel = uilabel(app.MedianFilterTab);
            app.DLabel.Text = '0.2';
            app.NoiseDensityLabel = uilabel(app.MedianFilterTab);
            app.NoiseDensityLabel.Text = 'Noise Density';
            app.DSlider = uislider(app.MedianFilterTab);
            app.DSlider.Limits = [0 1];
            app.DSlider.ValueChangedFcn = createCallbackFcn(app, @DSliderValueChanged, true);
            app.DSlider.ValueChangingFcn = createCallbackFcn(app, @DSliderValueChanging, true);
            app.DSlider.Value = 0.2;
            app.WSLabel = uilabel(app.MedianFilterTab);
            app.WSLabel.Text = '50';
            app.WindowSizeLabel = uilabel(app.MedianFilterTab);
            app.WindowSizeLabel.Text = 'Window Size';
            app.WSSlider = uislider(app.MedianFilterTab);
            app.WSSlider.Limits = [1 50];
            app.WSSlider.ValueChangedFcn = createCallbackFcn(app, @WSSliderValueChanged, true);
            app.WSSlider.ValueChangingFcn = createCallbackFcn(app, @WSSliderValueChanging, true);
            app.WSSlider.Value = 3;
            app.NoiseFilteringTab = uitab(app.TabGroup);
            app.NoiseFilteringTab.Title = 'Noise Filtering';
            app.ImgOriNFAxes = uiaxes(app.NoiseFilteringTab);
            title(app.ImgOriNFAxes, 'Image Input')
            app.ImgOutNFAxes = uiaxes(app.NoiseFilteringTab);
            title(app.ImgOutNFAxes, 'Image Output')
            app.SpectrumNFAxes = uiaxes(app.NoiseFilteringTab);
            title(app.SpectrumNFAxes, 'Spectrum Image Output')
            app.AddNotchFilterTextAreaLabel = uilabel(app.NoiseFilteringTab);
            app.AddNotchFilterTextAreaLabel.Text = 'Add Notch Filter';
            app.AddNotchFilterTextArea = uitextarea(app.NoiseFilteringTab);
            app.AddImageNFButton = uibutton(app.NoiseFilteringTab, 'push');
            app.AddImageNFButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageNFButtonPushed, true);
            app.AddImageNFButton.Text = 'Add Image';
            app.ApplyNFButton = uibutton(app.NoiseFilteringTab, 'push');
            app.ApplyNFButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyNFButtonPushed, true);
            app.ApplyNFButton.Text = 'Apply Notch Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end